classdef Data_Acquisition_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InsertfolderButton         matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        ImagenumberEditField       matlab.ui.control.NumericEditField
        ImagenumberEditFieldLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FilesList
        prefix 
        suffix
        numImages
        filename
        img 
        a=0 % 0 is on, 1 is off
    end
    
methods (Access = private)
        
%   function app.plot_intensity(~, file, roi)
%     % Read in the image
%     app.img = imread(file);
%     rot_img = imrotate(app.img,-90); %rotate image closewise 90 degree
%     
%     % Convert to gray scale
%     gray_img = im2gray(rot_img);
% 
%     % Extract the coordinate of the ROI (Original point on the top left of img)
%     x1 = roi(1); % x-coordinate of the upper-left corner of the rectangle.
%     y1 = roi(2); % y-coordinate of the upper-left corner of the rectangle.
% 
%     % Initialize a matrix to store gray value
%     gray_value = zeros(roi(4)+1,roi(3)+1); % #rows x #columns
% 
%     % Loop over every pixel in the ROI and calculate the gray value
%     for j = 1:roi(3)
%         for i = 1:roi(4)
%             % Calculate the gray value at each coordinate/pixel
%             gray_value(i,j) = gray_img(y1-1+i, x1-1+j);
%         end
%     end
% 
%     % Intensity at each pixel
%     Intensity = mean(gray_value); % mean of each column
%     % Distance for every pixel
%     dist = zeros(1,roi(3)+1);
%     for k = 1:(roi(3)+1)
%         dist(k) = x1-1+k;
%     end
%     
%     % Plot Intenity at each distance of pixel with a different color
%     plot(dist,Intensity,'LineWidth',2)
%     xlabel('Pixel Number');
%     ylabel('Intensity');
%     title('Intensity Profile');
%    end
% 

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertfolderButton
        function InsertfolderButtonPushed(app, event)
            % Open folder selection dialog box
            % Input the shortcut path to the folder address
            app.FilesList = uigetdir("C:\Users\hoang\Documents\RU Subjects " + ...
                "Senior\Spring 2023 Subjects\Aresty Research\data\");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

% Check if the button text is 'Start'
if strcmp(app.StartButton.Text, 'Start')
    % Change button text and color
    app.StartButton.Text = 'Stop';
    app.StartButton.BackgroundColor = 'red';
    % Define the prefix and suffix of the image names
    app.prefix = 'Image';
    app.suffix = '.tiff';
    % Get the number of images in the folder
    app.numImages = numel(dir([app.FilesList '/*.tiff']));

    % Set a flag variable to stop the loop
    app.a = true;
    % Loop through the images and display them with a time delay
        for i = 1:18:app.numImages 
            % Check if the flag variable is still true
            if app.a
                % Construct the filename using sprintf with format string
                app.filename = fullfile(app.FilesList, sprintf('%s%05d%s', app.prefix, i, app.suffix));
                % Load the image
                if isequal(app.filename, 0)
                    figure(app.UIFigure)
                    return;
                end
                app.img = imread(app.filename);
                % Display the image on the UIAxes
                imshow(app.img, 'Parent', app.UIAxes);
                drawnow;
                % Pause for 2 seconds
                pause(2); % Change the delay time as needed
                % Update the image number edit field
                app.ImagenumberEditField.Value = i;
            else
                % Stop the loop if the flag variable is false
                break;
            end
        end

    % Change button text and color back to the original state
    app.StartButton.Text = 'Start';
    app.StartButton.BackgroundColor = 'green';
else % if the button text is 'Stop'
    % Set the flag variable to false to stop the loop
    app.a = false;
end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image Aquisition')
            app.UIAxes.Visible = 'off';
            app.ImagenumberEditFieldLabel = uilabel(app.UIFigure);
            app.ImagenumberEditFieldLabel.Text = 'Image number';
            app.ImagenumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InsertfolderButton = uibutton(app.UIFigure, 'push');
            app.InsertfolderButton.ButtonPushedFcn = createCallbackFcn(app, @InsertfolderButtonPushed, true);
            app.InsertfolderButton.Text = 'Insert folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_Acquisition_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end